classdef forceplate_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ForceplateProcessingtoolUIFigure  matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        LoadfileMenu                matlab.ui.container.Menu
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes3_2                   matlab.ui.control.UIAxes
        UITable                     matlab.ui.control.Table
        ColumnselectADropDownLabel  matlab.ui.control.Label
        ColumnselectADropDown       matlab.ui.control.DropDown
        ColumnselectBDropDownLabel  matlab.ui.control.Label
        ColumnselectBDropDown       matlab.ui.control.DropDown
        DataselectDropDownLabel     matlab.ui.control.Label
        DataselectDropDown          matlab.ui.control.DropDown
        CalculatepeaksButton        matlab.ui.control.Button
        SmoothandNormalizeButton    matlab.ui.control.Button
        ResetdataButton             matlab.ui.control.Button
        ProminenceSpinnerLabel      matlab.ui.control.Label
        ProminenceSpinner           matlab.ui.control.Spinner
        HorizontalSliderLabel       matlab.ui.control.Label
        HorizontalSlider            matlab.ui.control.Slider
        VerticalSliderLabel         matlab.ui.control.Label
        VerticalSlider              matlab.ui.control.Slider
        CalculatejumpheightButton   matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        calculateimpulseButton      matlab.ui.control.Button
    end

    methods (Access = private)
        function newData = smoothAndNormalize(app, data)
            % smoothAndNormalize first smooths the given data and sets the
            % smallest value as the zero point, meaning that [15 10 12]
            % will become [5 0 2].
            newData = smoothdata(data,'gaussian', 50);
            newData = newData - min(newData);
            
        end
        
        function [] = loadData(app)
            % LOADDATA Loads data from text file
            %
            %
            global signals WIDTH_FP LENGTH_FP FP_A_time FP_A_0 FP_A_1 FP_A_2 FP_A_3 FP_B_time FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_A_TOTAL FP_B_TOTAL CoP_A_X CoP_A_Y CoP_B_X CoP_B_Y;
            C = 406.831;
            nbits = 16;
            WIDTH_FP = 450;
            LENGTH_FP = 450;
            FP_A_Vfs0 = 2.00058;
            FP_A_Vfs1 = 2.00046;
            FP_A_Vfs2 = 2.00067;
            FP_A_Vfs3 = 2.00086;
            FP_B_Vfs0 = 1.99959;
            FP_B_Vfs1 = 1.99998;
            FP_B_Vfs2 = 1.99995;
            FP_B_Vfs3 = 1.99992;
            FP_A_time = signals(:,1);
            FP_A_0 = signals(:,3)*C/(FP_A_Vfs0*(2^nbits - 1));
            FP_A_1 = signals(:,4)*C/(FP_A_Vfs1*(2^nbits - 1));
            FP_A_2 = signals(:,5)*C/(FP_A_Vfs2*(2^nbits - 1));
            FP_A_3 = signals(:,6)*C/(FP_A_Vfs3*(2^nbits - 1));
            FP_A_TOTAL = FP_A_0 + FP_A_1 + FP_A_2 + FP_A_3;
            FP_B_time = signals(:,7);
            FP_B_0 = signals(:,9)*C/(FP_B_Vfs0*(2^nbits - 1));
            FP_B_1 = signals(:,10)*C/(FP_B_Vfs1*(2^nbits - 1));
            FP_B_2 = signals(:,11)*C/(FP_B_Vfs2*(2^nbits - 1));
            FP_B_3 = signals(:,12)*C/(FP_B_Vfs3*(2^nbits - 1));
            FP_B_TOTAL = FP_B_0 + FP_B_1 + FP_B_2 + FP_B_3;
            CoP_A_X = (WIDTH_FP/2).*((FP_A_1 + FP_A_2 - FP_A_1 - FP_A_3)./FP_A_TOTAL);
            CoP_A_Y = (LENGTH_FP/2).*((FP_A_0 + FP_A_1 - FP_A_2 - FP_A_3)./FP_A_TOTAL);
            CoP_B_X = (WIDTH_FP/2).*((FP_B_1 + FP_B_2 - FP_B_1 - FP_B_3)./FP_B_TOTAL);
            CoP_B_Y = (LENGTH_FP/2).*((FP_B_0 + FP_B_1 - FP_B_2 - FP_B_3)./FP_B_TOTAL); 
        end
        
        % xVar and yMax define the x-axis and y-axis
        % check also on impulse, we don't want to change the sliders if we choose impulse
        function [] = plotData(app, xVar, yVars, graphic, yMax, imp)
            % a handy function to plot data to a given graphic. It clears the
            % graph, plots the dependant variabels using the values of
            % the matrix yVars in function of the independant variable xVar.
            % It also rescales axi of the graph. yMax is the maximum value
            % of the dependant variables. This allows more freedom.
            % Finally it also changes the sliders of the app.
            cla(graphic);
            plot(graphic, xVar, yVars);
            axis(graphic, [0 length(xVar), min(min(yVars)) yMax])
            if (imp ~= 1)
                app.HorizontalSlider.Limits = [0 length(xVar)];
                app.VerticalSlider.Limits = [0 yMax]; 
            end
        end
        
        % function to get all valleys given by prominence
        function [val, locs] = valleysProminence(app, totalValleys, totalLocs)
           % global variables to get jump time
           global t0 t1;
           tempVAL = []; tempLOC = []; a = 1;
           for i = length(totalValleys):-1:2
               % check if valley is in jump time
                if (totalValleys(i) <= 0 && totalValleys(i) >= -1)
                    tempVAL(a) = totalValleys(i);
                    tempLOC(a) = totalLocs(i);
                    a = a+1;
                end
                % check if valleys are within the right range
                % if not delete them
                if (totalValleys(i) >= totalValleys(i-1) - app.ProminenceSpinner.Value && totalValleys(i) <= totalValleys(i-1) + app.ProminenceSpinner.Value)
                    totalValleys(i) = [];
                    totalLocs(i) = [];
                end
           end
            t0 = tempLOC(1); t1 = tempLOC(end);
            % add the start of jump
            totalValleys(length(totalValleys) + 1) = tempVAL(1);
            totalLocs(length(totalLocs) + 1) = tempLOC(1);
            % add the end of jump
            totalValleys(length(totalValleys) + 1) = tempVAL(end);
            totalLocs(length(totalLocs) + 1) = tempLOC(end);
            % assign the return values
           val = totalValleys;
           locs = totalLocs;
        end
        
        % find peaks and valleys in horizontal range
        % checkVal to define peaks or valleys
        function [tempVals, tempLocs] = findInRange(app, LOCS, VALS, checkVal)
        %FINDINRANGE Finds the location and values in a range. This range is specified by the value of the horizontal slider.
        %
        % LOCS is an array containing the time points.
        % VALS is an array containing the corresponding values.
        % checkVal is an integer (0 or 1) used to check if the function parameters are for peaks or valleys.
        %
        % The variables tempVals and tempLocs contain respectively the
        % values and their location that were found to be in the range.
        %
        % These two variables are the return values of the function
        %
            tempLow = 10000; tempHigh = 0;
            tempLocs = []; tempVals = [];
            tempVal = 0; tempLoc = 0;
            a = 1;
            for i = app.UIAxes.XLim(2):-app.HorizontalSlider.Value:app.HorizontalSlider.Value
                for j = length(LOCS):-1:2
                    % check if loc is between the range
                    if (LOCS(j) > i-app.HorizontalSlider.Value && LOCS(j) <= i)
                        %check for peak or valley
                        if (checkVal == 0)
                            % check if x is greater
                            if (VALS(j) >= tempHigh)
                                %temporal high value
                                tempHigh = LOCS(j);
                                %location
                                tempLoc = LOCS(j);
                                %value
                                tempVal = VALS(j);
                            end
                        else
                            % check if x is smaller
                            if (abs(VALS(j)) <= tempLow)
                                tempLow = LOCS(j);
                                 %location
                                tempLoc = LOCS(j);
                                 %value
                                tempVal = VALS(j);
                            end
                        end
                    end
                end
                % add location and value to corresponding arrrays
                tempVals(a) = tempVal;
                tempLocs(a) = tempLoc;
                a = a + 1;
                tempHigh = 0;
                tempLow = 10000;
            end
        end
        
        % findpeaks on the vertical slider
        function [VALS, LOCS] = findInVerticalSlider(app, VALS, LOCS, checkVal)
        %findInVerticalSlider Finds the peaks that are in a range. This 
        % range is specified by the value of the vertical slider.
        %
        % LOCS is an array containing the time points.
        % VALS is an array containing the corresponding values.
        % checkVal is an integer (0 or 1) used to check if the function parameters are for peaks or valleys.
        %
        % The function parameters are also the return values
        %
            %peaks
            if (checkVal == 0)
                for i = length(LOCS):-1:2
                    if (VALS(i) > app.VerticalSlider.Value)
                        VALS(i) = [];
                        LOCS(i) = [];
                    end
                end
            else
                %valleys
                for i = length(LOCS):-1:2
                    if (abs(VALS(i)) > app.VerticalSlider.Value)
                        %if the value the greater than value of slider,
                        %remove value from array
                        VALS(i) = [];
                        LOCS(i) = [];
                    end
                end
            end
        end
        
        % set all low values to zero to normalize the graph
        function [array] = zeros(app,array)
         %ZEROS Changes the values in array that are below 0.5 to zero.
         % This is done to get zero values during jump.
         % "array" is the array containing the values and this is also the
         % return value.
            for i = length(array):-1:2
                if (array(i) >=0 && array(i) <= 0.5)
                    %value value to zero
                    array(i) = 0;
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadfileMenu
        function LoadfileMenuSelected(app, event)
            global signals WIDTH_FP LENGTH_FP FP_A_time FP_A_0 FP_A_1 FP_A_2 FP_A_3 FP_B_time FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_A_TOTAL FP_B_TOTAL CoP_A_X CoP_A_Y CoP_B_X CoP_B_Y;
            filename = uigetfile('*.txt');
            fID = fopen(filename);
            datacell = textscan(fID,'%f%f%f%f%f%f%f%f%f%f%f%f', 'HeaderLines', 3, 'CollectOutput', 1);
            fclose(fID);
            signals= datacell{1};
            signals(:,12);
            
            ResetdataButtonPushed(app)
            
        end

        % Value changed function: ColumnselectADropDown
        function ColumnselectADropDownValueChanged(app, event)
            global FP_A_time FP_A_0 FP_A_1 FP_A_2 FP_A_3 FP_A_TOTAL FP_B_time FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_B_TOTAL;
            value = app.ColumnselectADropDown.Value;
            switch value
                case '1'
                    hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_0);
                    legend(app.UIAxes,"FP_A_0");
                case '2'
                    hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_1);
                    legend(app.UIAxes,"FP_A_1");
                case '3'
                    hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_2);
                    legend(app.UIAxes,"FP_A_2");
                case '4'
                    hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_3);
                    legend(app.UIAxes,"FP_A_3");
                case '1-4'
                    hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_0);
                    hold(app.UIAxes, "on");
                    plot(app.UIAxes,FP_A_time, FP_A_1);
                    plot(app.UIAxes,FP_A_time, FP_A_2);
                    plot(app.UIAxes,FP_A_time, FP_A_3);
                    legend(app.UIAxes,"FP_A_0", "FP_A_1", "FP_A_2", "FP_A_3");
                case 'SUM'
                    hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_TOTAL);
                    legend(app.UIAxes,"FP A SUM");
                case 'ALL'
                    hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_0);
                    hold(app.UIAxes, "on");
                    plot(app.UIAxes,FP_A_time, FP_A_1);
                    plot(app.UIAxes,FP_A_time, FP_A_2);
                    plot(app.UIAxes,FP_A_time, FP_A_3);
                    plot(app.UIAxes,FP_A_time, FP_A_TOTAL);
                    legend(app.UIAxes,"FP_A_0", "FP_A_1", "FP_A_2", "FP_A_3", "FP A SUM");
                case 'A + B'
                    % Shows the "sum" graphs of both A and B. 
                    y = FP_A_TOTAL;
                    i = find(FP_B_TOTAL==max(FP_B_TOTAL),1,"first") -  find(FP_A_TOTAL==max(FP_A_TOTAL),1,"first");
                    
                    if(i < 0)
                        i = -i;
                        x = FP_B_time(1,(1):(end-i));
                        y = FP_B_TOTAL(1,1:end-i) + FP_A_TOTAL(1,1+i:end);
                        hold(app.UIAxes, "off");
                        plot(app.UIAxes,FP_A_time(1,1+i:end), FP_A_TOTAL(1,1+i:end));
                        hold(app.UIAxes, "on");
                        plot(app.UIAxes,FP_B_time(1,1:end-i), FP_B_TOTAL(1,1:end-i));
                        plot(app.UIAxes,x, y);
                    else
                        x = FP_A_time((1):(end-i),1);
                        size(x)
                        size(FP_A_TOTAL(1:end-i,1))
                        size(FP_B_TOTAL(1+i:end,1))
                        size(FP_A_TOTAL(1:end-i,1) + FP_B_TOTAL((1+i):end),1)
                        y = [FP_A_TOTAL(1:end-i,1), FP_B_TOTAL(1+i:end,1), FP_A_TOTAL(1:end-i,1) + FP_B_TOTAL((1+i):end)];
                        size(y)
                    end
                    plotData(app, x, y, app.UIAxes, max(max(y)), 0)
                    legend(app.UIAxes, "FP A SUM", "FP B SUM", "A+B");
                otherwise
            end
            
        end

        % Value changed function: ColumnselectBDropDown
        function ColumnselectBDropDownValueChanged(app, event)
            global FP_B_time FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_B_TOTAL;
            value = app.ColumnselectBDropDown.Value;
            switch value
                case '1'
                    hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_0);
                    legend(app.UIAxes2,"FP_B_0");
                case '2'
                    hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_1);
                    legend(app.UIAxes2,"FP_B_1");
                case '3'
                    hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_2);
                    legend(app.UIAxes2,"FP_B_2");
                case '4'
                    hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_3);
                    legend(app.UIAxes2,"FP_B_3");
                case '1-4'
                    hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_0);
                    hold(app.UIAxes2, "on");
                    plot(app.UIAxes2,FP_B_time, FP_B_1);
                    plot(app.UIAxes2,FP_B_time, FP_B_2);
                    plot(app.UIAxes2,FP_B_time, FP_B_3);
                    legend(app.UIAxes2,"FP_B_0", "FP_B_1", "FP_B_2", "FP_B_3");
                case 'SUM'
                    hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_TOTAL);
                    legend(app.UIAxes2,"FP A SUM");
                case 'ALL'
                    hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_0);
                    hold(app.UIAxes2, "on");
                    plot(app.UIAxes2,FP_B_time, FP_B_1);
                    plot(app.UIAxes2,FP_B_time, FP_B_2);
                    plot(app.UIAxes2,FP_B_time, FP_B_3);
                    plot(app.UIAxes2,FP_B_time, FP_B_TOTAL);
                    legend(app.UIAxes2,"FP_B_0", "FP_B_1", "FP_B_2", "FP_B_3", "FP B SUM");
                otherwise
            end
        end

        % Callback function
        function PeakValleyThresholdASliderValueChanged(app, event)
            
%             smoothed_for_peak_detect = smooth(FP_A_TOTAL);
%             smoothed_for_peak_detect = smooth(smoothed_for_peak_detect);
            
%             if(size(FP_A_TOTAL_PK_LOCS)>0)
%                 if (FP_A_TOTAL_PK_LOCS(1) > FP_A_TOTAL_VL_LOCS(1))
%                     %VALLEY BEFORE PEAK
%                     valley = FP_A_TOTAL_VALLEYS(1);
%                     for i = 1:size(FP_A_TOTAL_VL_LOCS)
%                         if(valley > FP_A_TOTAL_VALLEYS(i))
%                             valley = FP_A_TOTAL_VALLEYS(i);
%                         end
%                         
%                     end
%                 else
%                     %PEAK BEFORE VALLEY
%                 end
%             end
        end

        % Callback function
        function PeakValleyThresholdBSliderValueChanged(app, event)
          
            
        end

        % Value changed function: DataselectDropDown
        function DataselectDropDownValueChanged(app, event)
            global FP_A_time FP_A_0 FP_A_1 FP_A_2 FP_A_3 FP_B_time FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_A_TOTAL FP_B_TOTAL 
            global CoP_A_X CoP_A_Y CoP_B_X CoP_B_Y;
            global FP_A_TOTAL_PK_LOCS FP_A_TOTAL_PEAKS FP_A_TOTAL_VL_LOCS FP_A_TOTAL_VALLEYS FP_B_TOTAL_PK_LOCS FP_B_TOTAL_PEAKS FP_B_TOTAL_VL_LOCS FP_B_TOTAL_VALLEYS;
            value = app.DataselectDropDown.Value;
            switch value
                case 'A0'
                    app.UITable.Data = [FP_A_time FP_A_0];
                case 'A1'
                    app.UITable.Data = [FP_A_time FP_A_1];
                case 'A2'
                    app.UITable.Data = [FP_A_time FP_A_2];
                case 'A3'
                    app.UITable.Data = [FP_A_time FP_A_3];
                case 'A_SUM'
                    app.UITable.Data = [FP_A_time FP_A_TOTAL];
                case 'A_PEAKS'
                    app.UITable.Data = [FP_A_TOTAL_PK_LOCS FP_A_TOTAL_PEAKS];
                case 'A_VALLEYS'
                    app.UITable.Data = [FP_A_TOTAL_VL_LOCS FP_A_TOTAL_VALLEYS];
                case 'B0'
                    app.UITable.Data = [FP_B_time FP_B_0];
                case 'B1'
                    app.UITable.Data = [FP_B_time FP_B_1];
                case 'B2'
                    app.UITable.Data = [FP_B_time FP_B_2];
                case 'B3'
                    app.UITable.Data = [FP_B_time FP_B_3];
                case 'B_SUM'
                    app.UITable.Data = [FP_B_time FP_B_TOTAL];
                case 'B_PEAKS'
                    app.UITable.Data = [FP_B_TOTAL_PK_LOCS FP_B_TOTAL_PEAKS];
                case 'B_VALLEYS'
                    app.UITable.Data = [FP_B_TOTAL_VL_LOCS FP_B_TOTAL_VALLEYS];
            end
        end

        % Button pushed function: CalculatepeaksButton
        function CalculatepeaksButtonPushed(app, event)
            % reset the data and normalize it
            ResetdataButtonPushed(app, event);
            SmoothandNormalizeButtonPushed(app, event);
            global FP_A_time FP_B_time;
            global FP_A_TOTAL FP_B_TOTAL;
            global FP_A_TOTAL_PEAKS FP_A_TOTAL_PK_LOCS FP_A_TOTAL_VALLEYS FP_A_TOTAL_VL_LOCS;
            global FP_B_TOTAL_PEAKS FP_B_TOTAL_PK_LOCS FP_B_TOTAL_VALLEYS FP_B_TOTAL_VL_LOCS;
            
            % peak prominence: difference in peak height
            [FP_A_TOTAL_PEAKS, FP_A_TOTAL_PK_LOCS] = findpeaks(FP_A_TOTAL, "MinPeakHeight", 20, "MinPeakProminence", app.ProminenceSpinner.Value);
            invert_A_TOTAL = -FP_A_TOTAL;
            [FP_A_TOTAL_VALLEYS, FP_A_TOTAL_VL_LOCS] = findpeaks(invert_A_TOTAL);
            % backwards loop to delete useless valleys
            [FP_A_TOTAL_VALLEYS, FP_A_TOTAL_VL_LOCS] = valleysProminence(app, FP_A_TOTAL_VALLEYS, FP_A_TOTAL_VL_LOCS);
            FP_A_TOTAL_VALLEYS = -FP_A_TOTAL_VALLEYS;
            FP_A_TOTAL_PK_LOCS = FP_A_TOTAL_PK_LOCS + FP_A_time(1,1);
            FP_A_TOTAL_VL_LOCS = FP_A_TOTAL_VL_LOCS + FP_A_time(1,1);
            plot(app.UIAxes,FP_A_TOTAL_PK_LOCS, FP_A_TOTAL_PEAKS, "Marker","o", "LineStyle","none");
            plot(app.UIAxes,FP_A_TOTAL_VL_LOCS, FP_A_TOTAL_VALLEYS, "Marker","o", "LineStyle","none");
            legend(app.UIAxes,"FP_A_0", "FP_A_1", "FP_A_2", "FP_A_3", "FP A SUM", "FP A Peaks", "FP A Valleys");
            
            % peak prominence: difference in peak height
            [FP_B_TOTAL_PEAKS, FP_B_TOTAL_PK_LOCS] = findpeaks(FP_B_TOTAL, "MinPeakHeight", 20, "MinPeakProminence", app.ProminenceSpinner.Value);
            invert_B_TOTAL = -FP_B_TOTAL;
            [FP_B_TOTAL_VALLEYS, FP_B_TOTAL_VL_LOCS] = findpeaks(invert_B_TOTAL);
            %backwards loop to delete useless valleys
            [FP_B_TOTAL_VALLEYS, FP_B_TOTAL_VL_LOCS] = valleysProminence(app, FP_B_TOTAL_VALLEYS, FP_B_TOTAL_VL_LOCS);
            FP_B_TOTAL_VALLEYS = -FP_B_TOTAL_VALLEYS;
            FP_B_TOTAL_PK_LOCS = FP_B_TOTAL_PK_LOCS + FP_B_time(1,1);
            FP_B_TOTAL_VL_LOCS = FP_B_TOTAL_VL_LOCS + FP_B_time(1,1);
            plot(app.UIAxes2,FP_B_TOTAL_PK_LOCS, FP_B_TOTAL_PEAKS, "Marker","o", "LineStyle","none");
            plot(app.UIAxes2,FP_B_TOTAL_VL_LOCS, FP_B_TOTAL_VALLEYS, "Marker","o", "LineStyle","none");
            legend(app.UIAxes2,"FP_B_0", "FP_B_1", "FP_B_2", "FP_B_3", "FP B SUM", "FP B Peaks", "FP B Valleys");
        end

        % Button pushed function: SmoothandNormalizeButton
        function SmoothandNormalizeButtonPushed(app, event)
        % SmoothandNormalizeButtonPushed Smooths and normalises the different forces
        % of both force plates.
        % 
            global FP_A_0 FP_A_1 FP_A_2 FP_A_3 FP_A_TOTAL FP_A_time FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_B_TOTAL FP_B_time
            % use the function smoothAndNormalize to smooth and normalize forces for forceplate 1
            FP_A_0 = smoothAndNormalize(app, FP_A_0);
            FP_A_1 = smoothAndNormalize(app, FP_A_1);
            FP_A_2 = smoothAndNormalize(app, FP_A_2);
            FP_A_3 = smoothAndNormalize(app, FP_A_3);
            
            % add all forces of forceplat 1
            FP_A_TOTAL = FP_A_0 + FP_A_1 + FP_A_2 + FP_A_3;
            FP_A_time = FP_A_time - FP_A_time(1);
            % remove values that are below 0.5
            FP_A_0 = zeros(app,FP_A_0);
            FP_A_1 = zeros(app,FP_A_1);
            FP_A_2 = zeros(app,FP_A_2);
            FP_A_3 = zeros(app,FP_A_3);
            % use the function smoothAndNormalize to smooth and normalize forces for forceplate 2
            FP_B_0 = smoothAndNormalize(app, FP_B_0);
            FP_B_1 = smoothAndNormalize(app, FP_B_1);
            FP_B_2 = smoothAndNormalize(app, FP_B_2);
            FP_B_3 = smoothAndNormalize(app, FP_B_3);
            
            % add all forces of forceplat 2
            FP_B_TOTAL = FP_B_0 + FP_B_1 + FP_B_2 + FP_B_3;
            FP_B_time = FP_B_time - FP_B_time(1);  
            % remove values that are below 0.5
            FP_B_0 = zeros(app,FP_B_0);
            FP_B_1 = zeros(app,FP_B_1);
            FP_B_2 = zeros(app,FP_B_2);
            FP_B_3 = zeros(app,FP_B_3);
            
            ColumnselectADropDownValueChanged(app);
            ColumnselectBDropDownValueChanged(app);
            %CoP_A_X = (WIDTH_FP/2).*((FP_A_1 + FP_A_2 - FP_A_1 - FP_A_3)./FP_A_TOTAL);
            %CoP_A_Y = (LENGTH_FP/2).*((FP_A_0 + FP_A_1 - FP_A_2 - FP_A_3)./FP_A_TOTAL);
            %plot(app.UIAxes3,CoP_A_X, CoP_A_Y);
        end

        % Button pushed function: ResetdataButton
        function ResetdataButtonPushed(app, event)
            global signals WIDTH_FP LENGTH_FP FP_A_time FP_A_0 FP_A_1 FP_A_2 FP_A_3 FP_B_time FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_A_TOTAL FP_B_TOTAL CoP_A_X CoP_A_Y CoP_B_X CoP_B_Y;
            
            loadData(app);
            
            yMax = max(max([FP_A_TOTAL FP_B_TOTAL]));
            
            plotData(app, FP_A_time, [FP_A_0 FP_A_1 FP_A_2 FP_A_3 FP_A_TOTAL], app.UIAxes, yMax, 0)
            %hold(app.UIAxes, "off");
            %plot(app.UIAxes,FP_A_time, FP_A_0);
            %hold(app.UIAxes, "on");
            %plot(app.UIAxes,FP_A_time, FP_A_1);
            %plot(app.UIAxes,FP_A_time, FP_A_2);
            %plot(app.UIAxes,FP_A_time, FP_A_3);
            %plot(app.UIAxes,FP_A_time, FP_A_TOTAL);
            xlabel(app.UIAxes, "time[datapoints]");
            ylabel(app.UIAxes, "weight [kgf]");
            legend(app.UIAxes,"FP_A_0", "FP_A_1", "FP_A_2", "FP_A_3", "FP A SUM");
            
            plotData(app, FP_B_time, [FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_B_TOTAL], app.UIAxes2, yMax, 0)
            %hold(app.UIAxes2, "off");
            %plot(app.UIAxes2,FP_B_time, FP_B_0);
            %hold(app.UIAxes2, "on");
            %plot(app.UIAxes2,FP_B_time, FP_B_1);
            %plot(app.UIAxes2,FP_B_time, FP_B_2);
            %plot(app.UIAxes2,FP_B_time, FP_B_3);
            %plot(app.UIAxes2,FP_B_time, FP_B_TOTAL);
            xlabel(app.UIAxes2, "time[datapoints]");
            ylabel(app.UIAxes2, "weight [kgf]");
            legend(app.UIAxes2,"FP_B_0", "FP_B_1", "FP_B_2", "FP_B_3", "FP B SUM");
            
            hold(app.UIAxes3, "off");
            plot(app.UIAxes3,CoP_A_X, CoP_A_Y);
            xlabel(app.UIAxes3, "X[mm]");
            ylabel(app.UIAxes3, "Y[mm]"); 
           
            hold(app.UIAxes3_2, "off");
            plot(app.UIAxes3_2,CoP_B_X, CoP_B_Y);
            xlabel(app.UIAxes3_2, "X[mm]");
            ylabel(app.UIAxes3_2, "Y[mm]");
            
            DataselectDropDownValueChanged(app);
            %CalculatepeaksButtonPushed(app);
        end

        % Value changed function: ProminenceSpinner
        function ProminenceSpinnerValueChanged(app, event)
            
            global prominence;
            prominence = app.ProminenceSpinner.Value;
        end

        % Value changed function: HorizontalSlider
        function HorizontalSliderValueChanged(app, event)
            value = app.HorizontalSlider.Value;
        end

        % Value changed function: VerticalSlider
        function VerticalSliderValueChanged(app, event)
            ResetdataButtonPushed(app, event);
            SmoothandNormalizeButtonPushed(app, event);
            global FP_A_time FP_B_time;
            global FP_A_TOTAL FP_B_TOTAL;
            global FP_A_TOTAL_PEAKS FP_A_TOTAL_PK_LOCS FP_A_TOTAL_VALLEYS FP_A_TOTAL_VL_LOCS;
            global FP_B_TOTAL_PEAKS FP_B_TOTAL_PK_LOCS FP_B_TOTAL_VALLEYS FP_B_TOTAL_VL_LOCS;
            
            %peak prominence: difference in peak height: vertical
            [FP_A_TOTAL_PEAKS, FP_A_TOTAL_PK_LOCS] = findpeaks(FP_A_TOTAL);
            invert_A_TOTAL = -FP_A_TOTAL;
            [FP_A_TOTAL_VALLEYS, FP_A_TOTAL_VL_LOCS] = findpeaks(invert_A_TOTAL);
            
            % find peaks from value of vertical slider, 0 is for peaks
            [FP_A_TOTAL_PEAKS, FP_A_TOTAL_PK_LOCS] = findInVerticalSlider(app, FP_A_TOTAL_PEAKS, FP_A_TOTAL_PK_LOCS, 0);
            % find valleys from value of vertical slider, 1 is for valleys
            [FP_A_TOTAL_VALLEYS, FP_A_TOTAL_VL_LOCS] = findInVerticalSlider(app, FP_A_TOTAL_VALLEYS, FP_A_TOTAL_VL_LOCS, 1);
            FP_A_TOTAL = [FP_A_TOTAL_PEAKS, FP_A_TOTAL_PK_LOCS];
            
            % peaks in range
            [FP_A_TOTAL_PEAKS, FP_A_TOTAL_PK_LOCS] = findInRange(app, FP_A_TOTAL_PK_LOCS, FP_A_TOTAL_PEAKS, 0);
            % valleys in range
            [FP_A_TOTAL_VALLEYS, FP_A_TOTAL_VL_LOCS] = findInRange(app, FP_A_TOTAL_VL_LOCS, FP_A_TOTAL_VALLEYS, 1);
            FP_A_TOTAL_VALLEYS = -FP_A_TOTAL_VALLEYS;
            FP_A_TOTAL_PK_LOCS = FP_A_TOTAL_PK_LOCS + FP_A_time(1,1);
            FP_A_TOTAL_VL_LOCS = FP_A_TOTAL_VL_LOCS + FP_A_time(1,1);
            plot(app.UIAxes,FP_A_TOTAL_PK_LOCS, FP_A_TOTAL_PEAKS, "Marker","o", "LineStyle","none");
            plot(app.UIAxes,FP_A_TOTAL_VL_LOCS, FP_A_TOTAL_VALLEYS, "Marker","o", "LineStyle","none");
            legend(app.UIAxes,"FP_A_0", "FP_A_1", "FP_A_2", "FP_A_3", "FP A SUM", "FP A Peaks", "FP A Valleys");
            
            %peak prominence: difference in peak height: vertical
            [FP_B_TOTAL_PEAKS, FP_B_TOTAL_PK_LOCS] = findpeaks(FP_B_TOTAL);
            invert_B_TOTAL = -FP_B_TOTAL;
            [FP_B_TOTAL_VALLEYS, FP_B_TOTAL_VL_LOCS] = findpeaks(invert_B_TOTAL);
            
            % find peaks from value of vertical slider, 0 is for peaks
            [FP_B_TOTAL_PEAKS, FP_B_TOTAL_PK_LOCS] = findInVerticalSlider(app, FP_B_TOTAL_PEAKS, FP_B_TOTAL_PK_LOCS, 0);
            % find valleys from value of vertical slider, 1 is for valleys
            [FP_B_TOTAL_VALLEYS, FP_B_TOTAL_VL_LOCS] = findInVerticalSlider(app, FP_B_TOTAL_VALLEYS, FP_B_TOTAL_VL_LOCS, 1);
            FP_B_TOTAL = [FP_B_TOTAL_PEAKS, FP_B_TOTAL_PK_LOCS];
            
            % peaks in range
            [FP_B_TOTAL_PEAKS, FP_B_TOTAL_PK_LOCS] = findInRange(app, FP_B_TOTAL_PK_LOCS, FP_B_TOTAL_PEAKS, 0);
            % valleys in range
            [FP_B_TOTAL_VALLEYS, FP_B_TOTAL_VL_LOCS] = findInRange(app, FP_B_TOTAL_VL_LOCS, FP_B_TOTAL_VALLEYS, 1);

            FP_B_TOTAL_VALLEYS = -FP_B_TOTAL_VALLEYS;
            FP_B_TOTAL_PK_LOCS = FP_B_TOTAL_PK_LOCS + FP_B_time(1,1);
            FP_B_TOTAL_VL_LOCS = FP_B_TOTAL_VL_LOCS + FP_B_time(1,1);
            plot(app.UIAxes2,FP_B_TOTAL_PK_LOCS, FP_B_TOTAL_PEAKS, "Marker","o", "LineStyle","none");
            plot(app.UIAxes2,FP_B_TOTAL_VL_LOCS, FP_B_TOTAL_VALLEYS, "Marker","o", "LineStyle","none");
            legend(app.UIAxes2,"FP_B_0", "FP_B_1", "FP_B_2", "FP_B_3", "FP B SUM", "FP B Peaks", "FP B Valleys");
        end

        % Button pushed function: CalculatejumpheightButton
        function CalculatejumpheightButtonPushed(app, event)
            % calculates the jump height by calculating the amount of time
            % there is no pressure on the plate given the average
            % gravitational pull on earth
            global t0 t1;
            f = @(t0, t1) (9.81/2).*(((t1-t0)./2).^2);
            jmp = f(t0/1000, t1/1000);
            app.Label.Text = "Jump Height = " + jmp + " m";
        end

        % Button pushed function: calculateimpulseButton
        function CalculateImpulseButtonPushed(app, event)
            % calculates the impulse on the pressure plate
            global FP_A_time FP_B_time;
            global FP_A_TOTAL FP_B_TOTAL;
            
            impA = FP_A_TOTAL;
            impB = FP_B_TOTAL;
            impA(1) = 0;
            impB(1) = 0;
            
            for i = 2:length(FP_A_time)
                % the article said the impulse is integral from t0 to t1 of
                % F*t*dt
                % Wikipedia said impulse is integral from t0 to t1 of F*dt
                % (Graphs look the same but scale is different)
                % Going with the article:
                impA(i) = trapz(FP_A_time(1:i), (FP_A_TOTAL(1:i)-FP_A_TOTAL(1)).*FP_A_time(1:i));
                impB(i) = trapz(FP_B_time(1:i), (FP_B_TOTAL(1:i)-FP_B_TOTAL(1)).*FP_B_time(1:i));
            end
            
            plotData(app, FP_A_time, impA, app.UIAxes, max(impA), 1)
            plotData(app, FP_B_time, impB, app.UIAxes2, max(impB), 1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ForceplateProcessingtoolUIFigure = uifigure('Visible', 'off');
            app.ForceplateProcessingtoolUIFigure.Name = 'Forceplate Processing tool';
            app.Menu = uimenu(app.ForceplateProcessingtoolUIFigure);
            app.Menu.Text = 'Menu';
            app.LoadfileMenu = uimenu(app.Menu);
            app.LoadfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfileMenuSelected, true);
            app.LoadfileMenu.Text = 'Load file';
            app.UIAxes = uiaxes(app.ForceplateProcessingtoolUIFigure);
            title(app.UIAxes, 'Forces FP1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.ForceplateProcessingtoolUIFigure);
            title(app.UIAxes2, 'Forces FP2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.ForceplateProcessingtoolUIFigure);
            title(app.UIAxes3, 'CoP FP1')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3_2 = uiaxes(app.ForceplateProcessingtoolUIFigure);
            title(app.UIAxes3_2, 'CoP FP2')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.UITable = uitable(app.ForceplateProcessingtoolUIFigure);
            app.UITable.ColumnName = {'t[s]'; 'F'};
            app.UITable.RowName = {};
            app.ColumnselectADropDownLabel = uilabel(app.ForceplateProcessingtoolUIFigure);
            app.ColumnselectADropDownLabel.Text = 'Column select A';
            app.ColumnselectADropDown = uidropdown(app.ForceplateProcessingtoolUIFigure);
            app.ColumnselectADropDown.Items = {'1', '2', '3', '4', '1-4', 'SUM', 'ALL', 'A + B'};
            app.ColumnselectADropDown.ValueChangedFcn = createCallbackFcn(app, @ColumnselectADropDownValueChanged, true);
            app.ColumnselectADropDown.Value = 'ALL';
            app.ColumnselectBDropDownLabel = uilabel(app.ForceplateProcessingtoolUIFigure);
            app.ColumnselectBDropDownLabel.Text = 'Column select B';
            app.ColumnselectBDropDown = uidropdown(app.ForceplateProcessingtoolUIFigure);
            app.ColumnselectBDropDown.Items = {'1', '2', '3', '4', '1-4', 'SUM', 'ALL'};
            app.ColumnselectBDropDown.ValueChangedFcn = createCallbackFcn(app, @ColumnselectBDropDownValueChanged, true);
            app.ColumnselectBDropDown.Value = 'ALL';
            app.DataselectDropDownLabel = uilabel(app.ForceplateProcessingtoolUIFigure);
            app.DataselectDropDownLabel.Text = 'Data select';
            app.DataselectDropDown = uidropdown(app.ForceplateProcessingtoolUIFigure);
            app.DataselectDropDown.Items = {'A0', 'A1', 'A2', 'A3', 'A_SUM', 'A_PEAKS', 'A_VALLEYS', 'B0', 'B1', 'B2', 'B3', 'B_SUM', 'B_PEAKS', 'B_VALLEYS'};
            app.DataselectDropDown.ValueChangedFcn = createCallbackFcn(app, @DataselectDropDownValueChanged, true);
            app.DataselectDropDown.Value = 'A0';
            app.CalculatepeaksButton = uibutton(app.ForceplateProcessingtoolUIFigure, 'push');
            app.CalculatepeaksButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatepeaksButtonPushed, true);
            app.CalculatepeaksButton.Text = 'Calculate peaks';
            app.SmoothandNormalizeButton = uibutton(app.ForceplateProcessingtoolUIFigure, 'push');
            app.SmoothandNormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothandNormalizeButtonPushed, true);
            app.SmoothandNormalizeButton.Text = 'Smooth and Normalize';
            app.ResetdataButton = uibutton(app.ForceplateProcessingtoolUIFigure, 'push');
            app.ResetdataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetdataButtonPushed, true);
            app.ResetdataButton.Text = 'Reset data';
            app.ProminenceSpinnerLabel = uilabel(app.ForceplateProcessingtoolUIFigure);
            app.ProminenceSpinnerLabel.Text = 'Prominence';
            app.ProminenceSpinner = uispinner(app.ForceplateProcessingtoolUIFigure);
            app.ProminenceSpinner.ValueChangedFcn = createCallbackFcn(app, @ProminenceSpinnerValueChanged, true);
            app.HorizontalSliderLabel = uilabel(app.ForceplateProcessingtoolUIFigure);
            app.HorizontalSliderLabel.Text = 'Horizontal';
            app.HorizontalSlider = uislider(app.ForceplateProcessingtoolUIFigure);
            app.HorizontalSlider.ValueChangedFcn = createCallbackFcn(app, @HorizontalSliderValueChanged, true);
            app.VerticalSliderLabel = uilabel(app.ForceplateProcessingtoolUIFigure);
            app.VerticalSliderLabel.Text = 'Vertical';
            app.VerticalSlider = uislider(app.ForceplateProcessingtoolUIFigure);
            app.VerticalSlider.ValueChangedFcn = createCallbackFcn(app, @VerticalSliderValueChanged, true);
            app.CalculatejumpheightButton = uibutton(app.ForceplateProcessingtoolUIFigure, 'push');
            app.CalculatejumpheightButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatejumpheightButtonPushed, true);
            app.CalculatejumpheightButton.Text = 'Calculate jump height';
            app.Label = uilabel(app.ForceplateProcessingtoolUIFigure);
            app.Label.Text = '';
            app.calculateimpulseButton = uibutton(app.ForceplateProcessingtoolUIFigure, 'push');
            app.calculateimpulseButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateImpulseButtonPushed, true);
            app.calculateimpulseButton.Text = 'calculate impulse';
            % Show the figure after all components are created
            app.ForceplateProcessingtoolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = forceplate_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ForceplateProcessingtoolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ForceplateProcessingtoolUIFigure)
        end
    end
end